classdef Assignment1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu_2                 matlab.ui.container.Menu
        ReadDataMenu_2             matlab.ui.container.Menu
        DifferentiateMenu          matlab.ui.container.Menu
        Info                       matlab.ui.container.Menu
        HardwareMenu               matlab.ui.container.Menu
        InitArduinoMenu            matlab.ui.container.Menu
        TestArduinoMenu            matlab.ui.container.Menu
        NewInfoLamp                matlab.ui.control.Lamp
        NewInfoLampLabel           matlab.ui.control.Label
        ArduinoConnectedLamp       matlab.ui.control.Lamp
        ArduinoConnectedLampLabel  matlab.ui.control.Label
        RobotCreatedLamp           matlab.ui.control.Lamp
        RobotCreatedLampLabel      matlab.ui.control.Label
        DataReadLamp               matlab.ui.control.Lamp
        DataReadLampLabel          matlab.ui.control.Label
        TeachPanel                 matlab.ui.container.Panel
        OptimusButton              matlab.ui.control.Button
        RequestedLabel             matlab.ui.control.Label
        ActualLabel                matlab.ui.control.Label
        YawEditField_2             matlab.ui.control.EditField
        YawEditField_2Label        matlab.ui.control.Label
        PitchEditField_2           matlab.ui.control.EditField
        PitchEditField_2Label      matlab.ui.control.Label
        RollEditField_2            matlab.ui.control.EditField
        RollEditField_2Label       matlab.ui.control.Label
        ZActualEditField           matlab.ui.control.EditField
        ZEditField_2Label          matlab.ui.control.Label
        YActualEditField           matlab.ui.control.EditField
        YEditField_2Label          matlab.ui.control.Label
        XActualEditField           matlab.ui.control.EditField
        XEditField_2Label          matlab.ui.control.Label
        YawEditField               matlab.ui.control.EditField
        YawEditFieldLabel          matlab.ui.control.Label
        PitchEditField             matlab.ui.control.EditField
        PitchEditFieldLabel        matlab.ui.control.Label
        RollEditField              matlab.ui.control.EditField
        RollEditFieldLabel         matlab.ui.control.Label
        ZEditField                 matlab.ui.control.EditField
        ZEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.EditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.EditField
        XEditFieldLabel            matlab.ui.control.Label
        DenavitHartenbergPanel     matlab.ui.container.Panel
        Joint3Slider               matlab.ui.control.Slider
        Joint3SliderLabel          matlab.ui.control.Label
        Joint2Slider               matlab.ui.control.Slider
        Joint2SliderLabel          matlab.ui.control.Label
        Joint1Slider               matlab.ui.control.Slider
        Joint1SliderLabel          matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        InfoTextArea               matlab.ui.control.TextArea
        InfoTextAreaLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        %  --------------------------- %
        %     Member Variables         %
        %  --------------------------- %

        Ariyo =[];
        joint_angles= [0 0];


        teach_points = [];
    end

    methods (Access = private)

        %  --------------------------- %
        %           Update             %
        %  --------------------------- %

        % Update UI
        %
        function update_UI(app)
            % Robot
            app.Ariyo.plot(app.joint_angles);
            % EE
            app.update_actual_EE;
            % Sliders
            app.update_joint_sliders();
        end

        % Update Joint Sliders
        %
        function update_joint_sliders(app)
            sliderList = [app.Joint1Slider, app.Joint2Slider, app.Joint3Slider];
            for i = 1:length(app.joint_angles)
                angle = app.joint_angles(i);
                if sliderList(i).Value ~= rad2deg(angle)
                    sliderList(i).ValueChangingFcn = [];
                    sliderList(i).Value = rad2deg(angle);
                    sliderList(i).ValueChangingFcn = createCallbackFcn(app, @joint_changing, true);
                end
            end
        end

        %   Update Table DH
        %
        function update_table_DH(app)
            numLinks = length(app.Ariyo.links);
            tableData = cell(numLinks, 6);
            for i = 1:numLinks
                link = app.Ariyo.links(i);
                tableData{i, 1} = ['Joint ', num2str(i)];   % Theta  - rotation angle
                tableData{i, 2} = link.d;                   % d      - link offset
                tableData{i, 3} = link.a;                   % a      - link length
                tableData{i, 4} = rad2deg(link.alpha);      % Alpha  - link twist
                tableData{i, 5} = rad2deg(link.offset);     % Offset - joint angle offset
                tableData{i, 6} = 0;                        % Sigma  - joint type (0 for revolute, 1 for prismatic)
            end
            app.UITable.Data = tableData;
        end

        %   Update Actual End Effector
        %
        function update_actual_EE(app)

            T = app.Ariyo.fkine(app.joint_angles);

            % if no fkine solution
            if(isempty(T))
                app.set_info("Error! Update Actual EE: no valid fkine solution!");
                return;
            end

            endEffectorPosition = T.t;

            app.XActualEditField.Value = num2str(endEffectorPosition(1));
            app.YActualEditField.Value = num2str(endEffectorPosition(2));
            app.ZActualEditField.Value = num2str(endEffectorPosition(3));
        end

        %  --------------------------- %
        %     Helper Functions         %
        %  --------------------------- %

        %   Move End Effector
        %
        function move_EE(app, axis, position)

            % Grab fresh T from single source: Ariyo Serial Link Robot
            T = app.Ariyo.fkine(app.joint_angles);

            % if no fkine solution
            if(isempty(T))
                app.set_info("Error! Move EE: no valid fkine solution!");
                return;
            end

            trans_vec = T.t;

            % Which Axis?
            switch axis
                case 'X'
                    trans_vec(1) = position;
                case 'Y'
                    trans_vec(2) = position;
                case 'Z'
                    trans_vec(3) = position;
                otherwise
                    app.set_info("Move End Effector: Not a valid axis for 2-DOF robot");
                    return;
            end

            % Create new T we want to move EE to, then solve the iKine
            T_new = SE3(trans_vec);

            temp_joint_angles = app.Ariyo.ikine(T_new, 'mask', [1 1 0 0 0 0]);  % Solve for X and Y only

            % if no ikine solution
            if(isempty(temp_joint_angles))
                app.set_info("Error! Move EE: no valid ikine solution!");
                return;
            end

            app.joint_angles = temp_joint_angles;

            app.update_UI();
        end

        %   Set Info
        %
        function set_info(app, text)
            app.InfoTextArea.Value = [text; ' '; app.InfoTextArea.Value];
            app.flash_lamp(app.NewInfoLamp, LampState.Green);
        end

        %  --------------------------- %
        %     Lamp Functions           %
        %  --------------------------- %

        % Flash Lamp
        %
        function flash_lamp(app, lamp, state)
            lamp.UserData.LampState = state;
            t = timer('ExecutionMode', 'fixedRate', ...                     % Execute repeatedly
                'Period', 0.5, ...                                    % Wait X seconds
                'TasksToExecute', 20, ...                             % Execute X times
                'TimerFcn', @(~,~) toggle_lamp(app, lamp), ...        % call periodically
                'StopFcn', @(~,~) end_lamp_flash(app, lamp, state));  % call at end
            % Start the timer
            start(t);
        end

        % Toggle Lamp
        %
        function toggle_lamp(app, lamp)
            % Toggle between state and white
            if lamp.UserData.LampToggled
                newState = lamp.UserData.LampState;
            else
                newState = LampState.White;
            end
            lamp.UserData.LampToggled = ~lamp.UserData.LampToggled;
            set_lamp_color(app, lamp, newState);
        end

        % End Lamp Flash
        %
        function end_lamp_flash(app, lamp, state)
            % New Info ends on a different color
            if lamp == app.NewInfoLamp
                state = LampState.Grey;
                app.NewInfoLamp.UserData.LampState = LampState.Grey;
            end
            % Ensure Lamp ends on the correct color
            set_lamp_color(app, lamp, state);
        end

        % Set Lamp Color
        %
        function set_lamp_color(app, lamp, state)
            switch state
                case LampState.Red
                    lamp.Color = [1, 0, 0];  % [Red, Green, Blue]
                case LampState.Yellow
                    lamp.Color = [1, 1, 0];
                case LampState.Green
                    lamp.Color = [0, 1, 0];
                case LampState.White
                    lamp.Color = [1, 1, 1];
                case LampState.Grey
                    lamp.Color = [0.8, 0.8, 0.8];
                otherwise
                    lamp.Color = [0.5, 0.5, 0.5];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup_function(app)
            % Init Lamp UserData
            app.NewInfoLamp.UserData.LampState = LampState.Red;
            app.NewInfoLamp.UserData.LampToggled = false;
            app.ArduinoConnectedLamp.UserData.LampState = LampState.Red;
            app.ArduinoConnectedLamp.UserData.LampToggled = false;
            app.RobotCreatedLamp.UserData.LampState = LampState.Red;
            app.RobotCreatedLamp.UserData.LampToggled = false;
            app.DataReadLamp.UserData.LampState = LampState.Red;
            app.DataReadLamp.UserData.LampToggled = false;

            % Create Robot
            app.create_robot;

            % Populate DH Table
            app.update_table_DH;

            % Read Data
            app.read_data;
        end

        % Menu selected function: ReadDataMenu_2
        function read_data(app, event)
            % Default File
            default_filename = 'teach_points.txt';

            % No robot_pose => ask user
            if exist(default_filename, 'file')
                filename = default_filename;
            else
                [fn, pn] = uigetfile('*.txt', 'Where is the data?');
                if isequal(fn, 0)
                    app.set_info('Info. Read Data: File selection canceled.');
                    return;
                end
                filename = fullfile(pn, fn);
            end

            % Open the file for reading
            fid = fopen(filename, 'r');
            if fid == -1
                app.set_info('Error! Read Data: Could not open file.');
                return;
            end

            % Read File to Data []
            app.teach_points = textscan(fid, '%s', 'Delimiter', '\n');  % Reads the file line by line

            % Close the file after reading
            fclose(fid);

            if isempty(app.teach_points{1})
                app.set_info('Error! Read Data: Could not read data');
                app.flash_lamp(app.DataReadLamp, LampState.Red);
            else
                cellData = app.teach_points{1};

                numArray = zeros(length(cellData), 4);

                for i = 1:length(cellData)
                    numArray(i, :) = str2num(cellData{i});
                end
                %app.Teach_Points = numArray;

                app.flash_lamp(app.DataReadLamp, LampState.Green);
                app.set_info('Success! Read Data');
                % Display the data read in
                disp('Data read in:');
                numArray
                
            end
        end

        % Menu selected function: Info
        function create_robot(app, event)
            % Skip if the robot already exists
            if isempty(app.Ariyo)

                L(1) = Link( 'd', 0, 'a', 7.5, 'alpha', 0, 'offset' , pi/2 );
                L(2) = Link( 'd', 2.6 , 'a', 9.4, 'alpha', 0, 'offset', pi/2);

                % Sets joint angles for # of elements in L
                app.joint_angles = zeros(1, numel(L));
                app.Ariyo = SerialLink(L, 'name', 'Ariyo', 'base', transl(10,0,8), ...
                    'tool', transl(0,0,-10.6));

                app.update_UI();

                app.set_info('Success! Create Robot.');
                newLampState =  LampState.Green;
                app.flash_lamp(app.RobotCreatedLamp, newLampState);
            end

        end

        % Cell edit callback: UITable
        function update_table(app, event)
            row = event.Indices(1);  % Get the row that was edited
            col = event.Indices(2);  % Get the column that was edited
            newValue = event.NewData;

            switch col
                % Skip 1  Theta
                case 2  % d
                    app.Ariyo.links(row).d = newValue;
                case 3  % a
                    app.Ariyo.links(row).a = newValue;
                case 4  % Alpha
                    app.Ariyo.links(row).alpha = deg2rad(newValue);
                    % Skip 5 Sigma
                case 6  % Offset
                    app.Ariyo.links(row).offset = deg2rad(newValue);
            end

            % Redraw the updated robot with current joint angles
            app.update_UI();
        end

        % Value changing function: Joint1Slider, Joint2Slider, Joint3Slider
        function joint_changing(app, event)
            changingValue = event.Value;

            % Which Slider called?
            switch event.Source
                case app.Joint1Slider
                    joint_index = 1;
                case app.Joint2Slider
                    joint_index = 2;
                case app.Joint3Slider
                    joint_index = 3;
                otherwise
                    return;
            end

            app.joint_angles(joint_index) = deg2rad(changingValue);

            % Update the UI
            app.update_UI();
        end

        % Key press function: UIFigure
        function key_press(app, event)
            keyPressed = event.Key;

            % Get fresh EE T from only source: Aryio
            T = app.Ariyo.fkine(app.joint_angles);

            % if no fkine solution
            if(isempty(T))
                app.set_info("Error! Key Press: no valid fkine solution!");
                return;
            end

            trans_vec = T.t;


            if isa(event, 'matlab.ui.eventdata.KeyData')

                stepSize = 0.5;

                switch keyPressed
                    case 'leftarrow'
                        trans_vec(1) = trans_vec(1) - stepSize;
                        axis = 'X';
                    case 'rightarrow'
                        trans_vec(1) = trans_vec(1) + stepSize;
                        axis = 'X';
                    case 'uparrow'
                        trans_vec(2) = trans_vec(2) + stepSize;
                        axis = 'Y';
                    case 'downarrow'
                        trans_vec(2) = trans_vec(2) - stepSize;
                        axis = 'Y';
                    otherwise
                        return;
                end

                app.XEditField.Value = num2str(trans_vec(1));
                app.YEditField.Value = num2str(trans_vec(2));

                app.move_EE(axis, trans_vec(find(axis == 'XY')));
            end

        end

        % Value changed function: XEditField, YEditField, ZEditField
        function request_end_effector(app, event)
            value = str2double(event.Value);

            % Clear all TODO remove events around this and re-add value in
            % case <- Clear All still not implemented, but probably not
            % needed.
            %app.XEditField.Value = '';
            %app.YEditField.Value = '';
            %app.ZEditField.Value = '';


            %
            if isnan(value)
                app.set_info("Please enter a valid numeric value");
                return;
            end

            % Which Axes Requested ?
            switch event.Source
                case app.XEditField
                    axis = 'X';
                case app.YEditField
                    axis = 'Y';
                case app.ZEditField
                    axis = 'Z';
                otherwise
                    return;
            end

            app.move_EE(axis, value);
        end

        % Button pushed function: OptimusButton
        function optimus_button_press(app, event)
            % TODO:
            % Call optimus here
            % Figure out what to do with output.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @key_press, true);
            app.FileMenu_2 = uimenu(app.UIFigure);
            app.FileMenu_2.Text = 'File';
            app.ReadDataMenu_2 = uimenu(app.FileMenu_2);
            app.ReadDataMenu_2.MenuSelectedFcn = createCallbackFcn(app, @read_data, true);
            app.ReadDataMenu_2.Text = 'ReadData';
            app.DifferentiateMenu = uimenu(app.FileMenu_2);
            app.DifferentiateMenu.Text = 'Differentiate';
            app.Info = uimenu(app.FileMenu_2);
            app.Info.MenuSelectedFcn = createCallbackFcn(app, @create_robot, true);
            app.Info.Text = 'CreateRobot';
            app.HardwareMenu = uimenu(app.UIFigure);
            app.HardwareMenu.Text = 'Hardware';
            app.InitArduinoMenu = uimenu(app.HardwareMenu);
            app.InitArduinoMenu.Text = 'InitArduino';
            app.TestArduinoMenu = uimenu(app.HardwareMenu);
            app.TestArduinoMenu.Text = 'TestArduino';
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.DenavitHartenbergPanel = uipanel(app.UIFigure);
            app.DenavitHartenbergPanel.Title = 'Denavit-Hartenberg';
            app.UITable = uitable(app.DenavitHartenbergPanel);
            app.UITable.ColumnName = {'Theta'; 'd'; 'a'; 'Alpha'; 'Offset'; 'Sigma'};
            app.UITable.RowName = {'L1'; 'L2'; 'L3'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @update_table, true);
            app.Joint1SliderLabel = uilabel(app.DenavitHartenbergPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.DenavitHartenbergPanel);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @joint_changing, true);
            app.Joint2SliderLabel = uilabel(app.DenavitHartenbergPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.DenavitHartenbergPanel);
            app.Joint2Slider.Limits = [-180 180];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @joint_changing, true);
            app.Joint3SliderLabel = uilabel(app.DenavitHartenbergPanel);
            app.Joint3SliderLabel.Visible = 'off';
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.DenavitHartenbergPanel);
            app.Joint3Slider.Limits = [0 12.5];
            app.Joint3Slider.MajorTicks = [0 3 6 9 12];
            app.Joint3Slider.MajorTickLabels = {'0', '3', '6', '9', '12'};
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @joint_changing, true);
            app.Joint3Slider.Visible = 'off';
            app.TeachPanel = uipanel(app.UIFigure);
            app.TeachPanel.Title = 'Teach';
            app.XEditFieldLabel = uilabel(app.TeachPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.TeachPanel, 'text');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @request_end_effector, true);
            app.YEditFieldLabel = uilabel(app.TeachPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.TeachPanel, 'text');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @request_end_effector, true);
            app.ZEditFieldLabel = uilabel(app.TeachPanel);
            app.ZEditFieldLabel.Enable = 'off';
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.TeachPanel, 'text');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @request_end_effector, true);
            app.ZEditField.Enable = 'off';
            app.RollEditFieldLabel = uilabel(app.TeachPanel);
            app.RollEditFieldLabel.Visible = 'off';
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.TeachPanel, 'text');
            app.RollEditField.Visible = 'off';
            app.PitchEditFieldLabel = uilabel(app.TeachPanel);
            app.PitchEditFieldLabel.Visible = 'off';
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.TeachPanel, 'text');
            app.PitchEditField.Visible = 'off';
            app.YawEditFieldLabel = uilabel(app.TeachPanel);
            app.YawEditFieldLabel.Visible = 'off';
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.TeachPanel, 'text');
            app.YawEditField.Visible = 'off';
            app.XEditField_2Label = uilabel(app.TeachPanel);
            app.XEditField_2Label.Text = 'X';
            app.XActualEditField = uieditfield(app.TeachPanel, 'text');
            app.YEditField_2Label = uilabel(app.TeachPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YActualEditField = uieditfield(app.TeachPanel, 'text');
            app.ZEditField_2Label = uilabel(app.TeachPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZActualEditField = uieditfield(app.TeachPanel, 'text');
            app.RollEditField_2Label = uilabel(app.TeachPanel);
            app.RollEditField_2Label.Visible = 'off';
            app.RollEditField_2Label.Text = 'Roll';
            app.RollEditField_2 = uieditfield(app.TeachPanel, 'text');
            app.RollEditField_2.Visible = 'off';
            app.PitchEditField_2Label = uilabel(app.TeachPanel);
            app.PitchEditField_2Label.Visible = 'off';
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.TeachPanel, 'text');
            app.PitchEditField_2.Visible = 'off';
            app.YawEditField_2Label = uilabel(app.TeachPanel);
            app.YawEditField_2Label.Visible = 'off';
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.TeachPanel, 'text');
            app.YawEditField_2.Visible = 'off';
            app.ActualLabel = uilabel(app.TeachPanel);
            app.ActualLabel.Text = 'Actual';
            app.RequestedLabel = uilabel(app.TeachPanel);
            app.RequestedLabel.Text = 'Requested';
            app.OptimusButton = uibutton(app.TeachPanel, 'push');
            app.OptimusButton.ButtonPushedFcn = createCallbackFcn(app, @optimus_button_press, true);
            app.OptimusButton.Text = 'Optimus';
            app.DataReadLampLabel = uilabel(app.UIFigure);
            app.DataReadLampLabel.Text = {'Data '; 'Read'};
            app.DataReadLamp = uilamp(app.UIFigure);
            app.RobotCreatedLampLabel = uilabel(app.UIFigure);
            app.RobotCreatedLampLabel.Text = {'Robot '; 'Created'};
            app.RobotCreatedLamp = uilamp(app.UIFigure);
            app.ArduinoConnectedLampLabel = uilabel(app.UIFigure);
            app.ArduinoConnectedLampLabel.Text = {'Arduino '; 'Connected'};
            app.ArduinoConnectedLamp = uilamp(app.UIFigure);
            app.NewInfoLampLabel = uilabel(app.UIFigure);
            app.NewInfoLampLabel.Text = {'New'; 'Info'};
            app.NewInfoLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assignment1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup_function)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end